classdef run_NBSPredictGUI_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NBSPredictAnalysisSetupUIFigure  matlab.ui.Figure
        AboutButton                matlab.ui.control.Button
        ManualButton               matlab.ui.control.Button
        ModelSelectionPanel        matlab.ui.container.Panel
        MLModelDropDown            matlab.ui.control.DropDown
        MLModelLabel               matlab.ui.control.Label
        ContrastEditField          matlab.ui.control.EditField
        ContrastLabel              matlab.ui.control.Label
        ModelEvaluationPanel       matlab.ui.container.Panel
        MetricDropDown             matlab.ui.control.DropDown
        MetricLabel                matlab.ui.control.Label
        PermutationEditField       matlab.ui.control.NumericEditField
        PermutationEditFieldLabel  matlab.ui.control.Label
        RepeatCVEditField          matlab.ui.control.NumericEditField
        RepeatCVEditFieldLabel     matlab.ui.control.Label
        KFoldsEditField            matlab.ui.control.NumericEditField
        KFoldsEditFieldLabel       matlab.ui.control.Label
        CVMethodDropDown           matlab.ui.control.DropDown
        CVMethodDropDownLabel      matlab.ui.control.Label
        FeatureEngineeringPanel    matlab.ui.container.Panel
        ScalingDropDown            matlab.ui.control.DropDown
        FeatureScalingLabel        matlab.ui.control.Label
        PvalueEditField            matlab.ui.control.NumericEditField
        PvalueEditFieldLabel       matlab.ui.control.Label
        HyperparameterOptimizationPanel  matlab.ui.container.Panel
        IterationsEditField        matlab.ui.control.NumericEditField
        IterationsLabel            matlab.ui.control.Label
        StepsEditField             matlab.ui.control.NumericEditField
        StepsEditFieldLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        MethodDropDownLabel        matlab.ui.control.Label
        AdditionalParametersPanel  matlab.ui.container.Panel
        CPUCoresEditField          matlab.ui.control.NumericEditField
        CPUCoresEditFieldLabel     matlab.ui.control.Label
        SeedEditField              matlab.ui.control.NumericEditField
        SeedEditFieldLabel         matlab.ui.control.Label
        RUNButton                  matlab.ui.control.Button
        Information                matlab.ui.control.Label
        NBSPredictLabel            matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        max_cores % maximum # of cpu cores.
        gui_history % Parameters to save for subsequent sessions. 
        wp_data % Parameters regarding workspace. 
        wp_dir % WP directory.
        ml_funs % ML function names.
        history_dir % Directory for history file.
        is_history
    end
    
    properties (Access = public)
        NBSPredict % main data structure of the toolbox.
    end
    
    methods (Access = private)
        
        function [] = load_history(app)
            % Load saved parameters.
            app.history_dir = fullfile(app.wp_dir, "history_updated.mat");
            if exist(app.history_dir, 'file') == 2
                app.is_history = 1;
                try
                    load(app.history_dir);
                    app.NBSPredict = gui_history.NBSPredict;
                    app.gui_history = gui_history;
                    if isfield(gui_history, 'UI')
                        callbacks = fieldnames(gui_history.UI.Value);
                        for c = 1: numel(callbacks)
                            app.(callbacks{c}).Value = ...
                                gui_history.UI.Value.(callbacks{c});
                        end
                    end
                        app.is_history = 0;
                catch
                    error('Error while loading parameters for %s.', ...
                        callbacks{c});
                end
            else
                app.is_history = 0;
            end
        end
        
        function results = isdouble(~, input)
            results = isreal(str2double(input)) & ~isnan(str2double(input));
        end
        
        function [] = assert_num(app, input)
            if ~app.isdouble(input)
                msg = 'Input must be a number!';
                errordlg(msg);
                error(msg);
            end
        end
        
        function [] = prep_options(app)
            if app.wp_data.ifClassif
                app.MLModelDropDown.Items = {'Auto (optimize models)',...
                    'Logistic Regression',...
                    'SVM Classification',...
                    'Linear Discriminant Analysis'};
                app.ml_funs = {'','LogReg','svmC','lda'};
                app.MetricDropDown.Items = {'Accuracy','Balanced_Accuracy',...
                    'Sensitivity','Specificity',...
                    'Precision','Recall','F1','Matthews_CC','Cohens_Kappa','AUC'};
            else
                app.MLModelDropDown.Items = {'Auto (optimize models)',...
                    'Linear Regression',...
                    'SVM Regression'};
                app.ml_funs = {'','LinReg','svmR'};
                app.MetricDropDown.Items = {'Correlation','RMSE','MSE','MAD',...
                    'Explained_Variance','R_squared'};
                app.CVMethodDropDown.Items = {'K-Fold'};

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NBSPredict.parameter.ifView = 1;
            app.NBSPredict.parameter.ifTest = 0;
            app.NBSPredict.info.version = '1.0.0-beta.11';
            app.NBSPredict.info.working_dir = pwd;
            app.wp_dir = pwd;
            app.max_cores = feature("numcores");
            
            app.wp_data = check_workspace(pwd);
            app.NBSPredict.data.corrPath = app.wp_data.corrPath;
            app.NBSPredict.data.brainRegionsPath = app.wp_data.brainRegionsPath;
            app.NBSPredict.data.designPath = app.wp_data.designPath;
            app.NBSPredict.parameter.contrast = [-1, 1];
            
            prep_options(app)
            % Load saved parameters. 
            load_history(app)

        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            if exist('MANUAL.pdf','file') == 2
                open('MANUAL.pdf');
            else
                msgbox('Cannot find the manual file!','Error','error');
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            [iconData, ~] = imread('NBS-Predict_logo.png');
            msg = {'NBS-Predict'; ['Version: ', app.NBSPredict.info.version];...
                'Author: Emin Serin';...
                'Contact: emin.serin@charite.de'};
            msgboxhanles = findall(msgbox(msg, 'About', 'custom', iconData),...
                'Type', 'Text'); 
            set(msgboxhanles, 'FontSize', 10);
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            value = app.ContrastEditField.Value;
            app.gui_history.UI.Value.ContrastEditField = value;
            tmp = strsplit(value(2:end-1), ",");
            tmp = cellfun(@(x) str2double(x), tmp);
            app.NBSPredict.parameter.contrast = tmp;
        end

        % Value changed function: MLModelDropDown
        function MLModelDropDownValueChanged(app, event)
            value = app.MLModelDropDown.Value;
            [~, idx] = ismember(value, app.MLModelDropDown.Items);
            ml_model = app.ml_funs(idx);
            if strcmpi(value, "Auto (optimize models)")
                app.NBSPredict.parameter.ifModelOpt = 1;
                if isfield(app.NBSPredict.parameter, "MLmodels")
                    app.NBSPredict.parameter = ...
                        rmfield(app.NBSPredict.parameter, "MLmodels");
                end
            else
                app.NBSPredict.parameter.ifModelOpt = 0;
                app.NBSPredict.parameter.MLmodels = ml_model;
            end
            app.gui_history.UI.Value.MLModelDropDown = value;
        end

        % Value changed function: CPUCoresEditField
        function CPUCoresEditFieldValueChanged(app, event)
            value = app.CPUCoresEditField.Value;
            app.NBSPredict.parameter.numCores = 1;
            if value < 1
                errordlg('Number of CPU cores cannot be lower than 1');
            elseif value > app.max_cores
                errordlg(sprintf('Number of CPU cores cannot be higher than %d physical cores',...
                    app.max_cores));
            else
                app.NBSPredict.parameter.numCores = value;
                app.gui_history.UI.Value.CPUCoresEditField = value;
            end
        end

        % Value changed function: SeedEditField
        function SeedEditFieldValueChanged(app, event)
            value = app.SeedEditField.Value;
            app.gui_history.UI.Value.SeedEditField = value;
            app.NBSPredict.parameter.randSeed = value;
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.gui_history.UI.Value.IterationsEditField = value; 
            app.NBSPredict.parameter.nIter = value;
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            value = app.StepsEditField.Value;
            app.gui_history.UI.Value.StepsEditField = value;
            app.NBSPredict.parameter.hyperOptSteps = value;
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if strcmpi(value, "No Optimization")
                app.NBSPredict.parameter.ifHyperOpt = 0;
                    app.IterationsEditField.Enable = "off";
                    app.StepsEditField.Enable = "off";
                    if isfield(app.NBSPredict.parameter, "selMethod")
                        app.NBSPredict.parameter.selMethod = ...
                            rmfield(app.NBSPredict.parameter, "selMethod");
                    end
            else
                app.NBSPredict.parameter.ifHyperOpt = 1;
                app.StepsEditField.Enable = "on";
                switch value
                    case "Grid Search"
                        app.NBSPredict.parameter.selMethod = "gridSearch";
                        app.IterationsEditField.Enable = "off";
                    case "Random Search"
                        app.NBSPredict.parameter.selMethod = "randomSearch";
                        app.IterationsEditField.Enable = "on";
                    case "Bayesian Optimization"
                        app.NBSPredict.parameter.selMethod = "bayesOpt";
                        app.IterationsEditField.Enable = "on";
                end
            end
            app.gui_history.UI.Value.MethodDropDown = value;
        end

        % Value changed function: ScalingDropDown
        function ScalingDropDownValueChanged(app, event)
            value = app.ScalingDropDown.Value;
            if strcmpi(value, "No Scaling")
                app.NBSPredict.parameter.scalingMethod = [];
            else
                app.NBSPredict.parameter.scalingMethod = value;
            end
            app.gui_history.UI.Value.ScalingDropDown = value;
        end

        % Value changed function: PvalueEditField
        function PvalueEditFieldValueChanged(app, event)
            value = app.PvalueEditField.Value;
            app.gui_history.UI.Value.PvalueEditField = value;
            app.NBSPredict.parameter.pVal = value;
        end

        % Value changed function: MetricDropDown
        function MetricDropDownValueChanged(app, event)
            value = app.MetricDropDown.Value;
            app.NBSPredict.parameter.metric = lower(value);
            app.gui_history.UI.Value.MetricDropDown = value;
        end

        % Value changed function: PermutationEditField
        function PermutationEditFieldValueChanged(app, event)
            value = app.PermutationEditField.Value;
            app.gui_history.UI.Value.PermutationEditField = value;
            if value > 0
                app.NBSPredict.parameter.ifPerm = 1;
                app.NBSPredict.parameter.permIter = value;
            end
        end

        % Value changed function: RepeatCVEditField
        function RepeatCVEditFieldValueChanged(app, event)
            value = app.RepeatCVEditField.Value;
            app.gui_history.UI.Value.RepeatCVEditField = value;
            app.NBSPredict.parameter.repCViter = value;
        end

        % Value changed function: KFoldsEditField
        function KFoldsEditFieldValueChanged(app, event)
            value = app.KFoldsEditField.Value;
            app.gui_history.UI.Value.KFoldsEditField = value;
            app.NBSPredict.parameter.kFold = value;
        end

        % Value changed function: CVMethodDropDown
        function CVMethodDropDownValueChanged(app, event)
            value = app.CVMethodDropDown.Value;
            if strcmpi(value, 'LOOCV')
                app.KFoldsEditField.Enable = "off";
                app.NBSPredict.parameter.kFold = -1;
            else
                app.KFoldsEditField.Enable = "on";
            end
            
            %%TODO: Add new CV methods!
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.gui_history.NBSPredict = app.NBSPredict;          
            if isfield(app.gui_history, 'UI')   
                gui_history = app.gui_history;
                save(app.history_dir, 'gui_history');
            end
            run_NBSPredict(app.NBSPredict);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NBSPredictAnalysisSetupUIFigure = uifigure('Visible', 'off');
            app.NBSPredictAnalysisSetupUIFigure.Name = 'NBS-Predict Analysis Setup';
            app.NBSPredictAnalysisSetupUIFigure.Icon = 'icon.png';
            app.Image = uiimage(app.NBSPredictAnalysisSetupUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon.png');
            app.NBSPredictLabel = uilabel(app.NBSPredictAnalysisSetupUIFigure);
            app.NBSPredictLabel.Text = 'NBS-Predict';
            app.Information = uilabel(app.NBSPredictAnalysisSetupUIFigure);
            app.Information.Text = 'Please set important parameters for your ML experiement!';
            app.RUNButton = uibutton(app.NBSPredictAnalysisSetupUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.AdditionalParametersPanel = uipanel(app.NBSPredictAnalysisSetupUIFigure);
            app.AdditionalParametersPanel.Title = 'Additional Parameters';
            app.SeedEditFieldLabel = uilabel(app.AdditionalParametersPanel);
            app.SeedEditFieldLabel.Text = 'Seed:';
            app.SeedEditField = uieditfield(app.AdditionalParametersPanel, 'numeric');
            app.SeedEditField.ValueChangedFcn = createCallbackFcn(app, @SeedEditFieldValueChanged, true);
            app.SeedEditField.Value = 42;
            app.CPUCoresEditFieldLabel = uilabel(app.AdditionalParametersPanel);
            app.CPUCoresEditFieldLabel.Text = 'CPU Cores';
            app.CPUCoresEditField = uieditfield(app.AdditionalParametersPanel, 'numeric');
            app.CPUCoresEditField.ValueChangedFcn = createCallbackFcn(app, @CPUCoresEditFieldValueChanged, true);
            app.CPUCoresEditField.Value = 1;
            app.HyperparameterOptimizationPanel = uipanel(app.NBSPredictAnalysisSetupUIFigure);
            app.HyperparameterOptimizationPanel.Title = 'Hyperparameter Optimization';
            app.MethodDropDownLabel = uilabel(app.HyperparameterOptimizationPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.HyperparameterOptimizationPanel);
            app.MethodDropDown.Items = {'No Optimization', 'Grid Search', 'Random Search', 'Bayesian Optimization'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'No Optimization';
            app.StepsEditFieldLabel = uilabel(app.HyperparameterOptimizationPanel);
            app.StepsEditFieldLabel.Text = 'Steps:';
            app.StepsEditField = uieditfield(app.HyperparameterOptimizationPanel, 'numeric');
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Enable = 'off';
            app.StepsEditField.Value = 5;
            app.IterationsLabel = uilabel(app.HyperparameterOptimizationPanel);
            app.IterationsLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.HyperparameterOptimizationPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.IterationsEditField.Value = 20;
            app.FeatureEngineeringPanel = uipanel(app.NBSPredictAnalysisSetupUIFigure);
            app.FeatureEngineeringPanel.Title = 'Feature Engineering';
            app.PvalueEditFieldLabel = uilabel(app.FeatureEngineeringPanel);
            app.PvalueEditFieldLabel.Text = 'P-value:';
            app.PvalueEditField = uieditfield(app.FeatureEngineeringPanel, 'numeric');
            app.PvalueEditField.ValueChangedFcn = createCallbackFcn(app, @PvalueEditFieldValueChanged, true);
            app.PvalueEditField.Value = 0.01;
            app.FeatureScalingLabel = uilabel(app.FeatureEngineeringPanel);
            app.FeatureScalingLabel.Text = 'Scaling:';
            app.ScalingDropDown = uidropdown(app.FeatureEngineeringPanel);
            app.ScalingDropDown.Items = {'No Scaling', 'MinMaxScaler', 'MaxAbsScaler', 'StandardScaler'};
            app.ScalingDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalingDropDownValueChanged, true);
            app.ScalingDropDown.Value = 'No Scaling';
            app.ModelEvaluationPanel = uipanel(app.NBSPredictAnalysisSetupUIFigure);
            app.ModelEvaluationPanel.Title = 'Model Evaluation';
            app.CVMethodDropDownLabel = uilabel(app.ModelEvaluationPanel);
            app.CVMethodDropDownLabel.Text = 'CV Method:';
            app.CVMethodDropDown = uidropdown(app.ModelEvaluationPanel);
            app.CVMethodDropDown.Items = {'K-Fold', 'LOOCV'};
            app.CVMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CVMethodDropDownValueChanged, true);
            app.CVMethodDropDown.Value = 'K-Fold';
            app.KFoldsEditFieldLabel = uilabel(app.ModelEvaluationPanel);
            app.KFoldsEditFieldLabel.Text = 'K-Folds:';
            app.KFoldsEditField = uieditfield(app.ModelEvaluationPanel, 'numeric');
            app.KFoldsEditField.ValueChangedFcn = createCallbackFcn(app, @KFoldsEditFieldValueChanged, true);
            app.KFoldsEditField.Value = 10;
            app.RepeatCVEditFieldLabel = uilabel(app.ModelEvaluationPanel);
            app.RepeatCVEditFieldLabel.Text = 'Repeat CV:';
            app.RepeatCVEditField = uieditfield(app.ModelEvaluationPanel, 'numeric');
            app.RepeatCVEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatCVEditFieldValueChanged, true);
            app.RepeatCVEditField.Value = 10;
            app.PermutationEditFieldLabel = uilabel(app.ModelEvaluationPanel);
            app.PermutationEditFieldLabel.Text = 'Permutation:';
            app.PermutationEditField = uieditfield(app.ModelEvaluationPanel, 'numeric');
            app.PermutationEditField.ValueChangedFcn = createCallbackFcn(app, @PermutationEditFieldValueChanged, true);
            app.MetricLabel = uilabel(app.ModelEvaluationPanel);
            app.MetricLabel.Text = 'Metric:';
            app.MetricDropDown = uidropdown(app.ModelEvaluationPanel);
            app.MetricDropDown.Items = {'Accuracy', 'Balanced_Accuracy', 'Sensitivity', 'Specificity', 'Precision', 'Recall', 'F1', 'Matthews_CC', 'Cohens_Kappa', 'AUC', 'MSE', 'RMSE', 'MAD', 'Correlation', 'R_Squared', 'Explained_Variance'};
            app.MetricDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.MetricDropDown.Value = 'Accuracy';
            app.ModelSelectionPanel = uipanel(app.NBSPredictAnalysisSetupUIFigure);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.ContrastLabel = uilabel(app.ModelSelectionPanel);
            app.ContrastLabel.Text = 'Contrast:';
            app.ContrastEditField = uieditfield(app.ModelSelectionPanel, 'text');
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.ContrastEditField.Value = '[-1, 1]';
            app.MLModelLabel = uilabel(app.ModelSelectionPanel);
            app.MLModelLabel.Text = 'ML Model:';
            app.MLModelDropDown = uidropdown(app.ModelSelectionPanel);
            app.MLModelDropDown.Items = {'Auto', 'SVM Classification', 'Logistic Regression', 'Linear Discriminant Analysis', 'SVM Regression', 'Linear Regression'};
            app.MLModelDropDown.ValueChangedFcn = createCallbackFcn(app, @MLModelDropDownValueChanged, true);
            app.MLModelDropDown.Value = 'Auto';
            app.ManualButton = uibutton(app.NBSPredictAnalysisSetupUIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.AboutButton = uibutton(app.NBSPredictAnalysisSetupUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.NBSPredictAnalysisSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_NBSPredictGUI_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NBSPredictAnalysisSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NBSPredictAnalysisSetupUIFigure)
        end
    end
end