classdef run_NBSPredictWorkspace_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WelcomeUIFigure     matlab.ui.Figure
        Image               matlab.ui.control.Image
        RemoveButton        matlab.ui.control.Button
        LoadButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        WorkspaceList       matlab.ui.control.ListBox
        OpenandexistingworkspaceListBoxLabel  matlab.ui.control.Label
        CreateButton        matlab.ui.control.Button
        WorkspaceEditField  matlab.ui.control.EditField
        WorkspaceLabel      matlab.ui.control.Label
        NBSPredictLabel     matlab.ui.control.Label
    end

    properties (Access = private)
        wp % Current workspace.
        wps % All workspaces
        wp_name % Current workspace name.
        wp_save_dir % Output workspace directory
    end

    methods (Access = private)

        function [] = update_workspace(app)
            app.WorkspaceList.Items = app.wps;
            app.wp = app.wps{1};
            app.WorkspaceList.Value = app.wp;
        end

        function [] = save_workspace(app)
            % Saves workspace into main NBS-Predict directory
            workspaces = app.wps;
            save(app.wp_save_dir, 'workspaces')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set Directory for WP Database.
            app.wp_save_dir = [fileparts(which('start_NBSPredict')),...
                filesep, 'workspaces.mat'];
            if exist(app.wp_save_dir, "file") == 2 
                load(app.wp_save_dir);
                exist_idx = [];
                for w = 1 : numel(workspaces)
                    if exist(workspaces{w}, "dir")
                        exist_idx = [exist_idx(:); w];
                    else
                        warning('The following directory cannot be found; thus, will be removed from the database: \n\n%s\n',...
                            workspaces{w})
                    end
                end
                workspaces = workspaces(exist_idx);
                save(app.wp_save_dir, "workspaces");
                if ~isempty(workspaces)
                    app.wps = workspaces;
                    app.WorkspaceList.Items = app.wps;
                    app.WorkspaceList.Value = app.wps{1};
                    app.wp = app.wps{1};
                else
                    msg = ['None of saved workspaces is found! ',...
                        'Please check your directory and load the workspaces again!'];
                    warning(msg)
                end
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if ~isempty(app.wps)
                [~, idx] = ismember(app.WorkspaceList.Value, ...
                    app.WorkspaceList.Items);
                app.wps(idx) = [];
                if ~isempty(app.wps)
                    update_workspace(app);
                else
                    app.WorkspaceList.Items = app.wps;
                    app.wp = [];
                end
                save_workspace(app)
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            wp_dir = uigetdir(pwd, 'Select workspace!');
            if all(wp_dir ~= 0)
                if ~any(strcmp(app.wps, wp_dir))
                    app.wp = wp_dir;
                    app.wps = [{wp_dir}, app.wps(:)'];
                    update_workspace(app)
                    save_workspace(app)
                else
                    warning('Given workspace is already in the database, so skipping.')
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             if ~isempty(app.WorkspaceList.Value)
                if exist(app.WorkspaceList.Value, "dir")
                    % Reorder WPs.
                    [~, idx] = ismember(app.WorkspaceList.Value, ...
                        app.WorkspaceList.Items);
                    app.wps(idx) = [];
                    app.wps = [{app.WorkspaceList.Value}, app.wps(:)'];
                    
                    % Closing the current window and open analysis window.
                    save_workspace(app);
                    cd(app.WorkspaceList.Value);
                    closereq;
                    try
                        run_NBSPredictGUI_updated();
                    catch 
                        closereq;
                        warning("The updated version of GUI is not compatible with your MATLAB version. The legacy version of GUI is starting...")
                        run_NBSPredictGUI();
                    end
                else
                    errordlg("The workspace cannot be found! Please check your directory!")
                end
             end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isprop(app, 'wp')
                if ~exist(app.wp, "dir")
                    status = mkdir(app.wp);
                    if status == 1
                        app.wps = [{app.wp}, app.wps(:)'];
                        try 
                            save_workspace(app)
                        catch
                            error("Workspace database cannot be saved! Check permissions!")
                        end
                        update_workspace(app)
                    else
                        errordlg('The workspace cannot be created! Please check permissions!')
                    end
                else
                    errordlg("The workspace already exists!")
                end
            end
        end

        % Value changed function: WorkspaceEditField
        function WorkspaceEditFieldValueChanged(app, event)
            app.wp = app.WorkspaceEditField.Value;
            [~, app.wp_name, ~] = fileparts(app.wp);
        end

        % Callback function
        function WorkspaceListClicked(app, event)
            idx = event.InteractionInformation.Item;
            if ~isempty(idx)
                app.wp = app.wps{idx};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WelcomeUIFigure = uifigure('Visible', 'off');
            colormap(app.WelcomeUIFigure, 'parula');
            app.WelcomeUIFigure.Name = 'Welcome!';
            app.NBSPredictLabel = uilabel(app.WelcomeUIFigure);
            app.NBSPredictLabel.Text = 'NBS-Predict';
            app.WorkspaceLabel = uilabel(app.WelcomeUIFigure);
            app.WorkspaceLabel.Text = ' Workspace:';
            app.WorkspaceEditField = uieditfield(app.WelcomeUIFigure, 'text');
            app.WorkspaceEditField.ValueChangedFcn = createCallbackFcn(app, @WorkspaceEditFieldValueChanged, true);
            app.CreateButton = uibutton(app.WelcomeUIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.OpenandexistingworkspaceListBoxLabel = uilabel(app.WelcomeUIFigure);
            app.OpenandexistingworkspaceListBoxLabel.Text = 'Open and existing workspace';
            app.WorkspaceList = uilistbox(app.WelcomeUIFigure);
            app.WorkspaceList.Items = {};
            app.WorkspaceList.Value = {};
            app.StartButton = uibutton(app.WelcomeUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LoadButton = uibutton(app.WelcomeUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RemoveButton = uibutton(app.WelcomeUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.Image = uiimage(app.WelcomeUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon.png');
            % Show the figure after all components are created
            app.WelcomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_NBSPredictWorkspace_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WelcomeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WelcomeUIFigure)
        end
    end
end