classdef view_NBSPredict_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NBSPredictViewerUIFigure  matlab.ui.Figure
        EvaluateSubnetworkButton  matlab.ui.control.StateButton
        ScoreLabel                matlab.ui.control.Label
        EdgesLabel                matlab.ui.control.Label
        NodesLabel                matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        ThresholdEditField        matlab.ui.control.NumericEditField
        ThresholdLabel            matlab.ui.control.Label
        ShowLabelsButton          matlab.ui.control.StateButton
        ModelDropDown             matlab.ui.control.DropDown
        ModelLabel                matlab.ui.control.Label
        MetricDropDown            matlab.ui.control.DropDown
        MetricLabel               matlab.ui.control.Label
        PlotTypeButtonGroup       matlab.ui.container.ButtonGroup
        ConfusionMatrixButton     matlab.ui.control.ToggleButton
        BrainNetViewerButton      matlab.ui.control.ToggleButton
        CircularNetworkButton     matlab.ui.control.ToggleButton
        AdjacencyMatrixButton     matlab.ui.control.ToggleButton
        Figure                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        plot_data % Description
        plot_results
        show_label %
        ml_model
        if_class
        cfig
    end

    methods (Access = private)

        function [] = update_confMat(app)

        end

        function [figTitle] = update_title(app)
            metric = app.plot_results.metric; 
            metricName = [upper(metric(1)),metric(2:end)];
            if strcmpi(app.plot_data.metric, metric)
                figTitle = sprintf('%s: %.3f (%.3f, %.3f)',metricName,...
                    app.plot_data.(app.ml_model).meanRepCVscore,...
                    app.plot_data.(app.ml_model).meanCVscoreCI);
                if isfield(app.plot_data.(app.ml_model), "permScore")
                    permScore = app.plot_data.(app.ml_model).permScore;
                    figTitle = sprintf([figTitle, ...
                        ' Permutation: %.3f, p = %.3f'], permScore(1),...
                        permScore(2));
                end
            else
                truePredLabels = app.plot_results.truePredLabels;
                if iscell(truePredLabels) && length(size(truePredLabels))==3
                    score = compute_mRCVscore(truePredLabels, metric);
                else
                    score = compute_modelMetrics(truePredLabels(:,1),...
                        truePredLabels(:,2),metric);
                end
                figTitle = sprintf('%s: %.3f',metricName,score);
            end
            title(app.Figure, figTitle)
        end

        function [] = plot_updateData(app)
            [app.plot_results.adj,app.plot_results.G, ...
                app.plot_results.labels, app.plot_results.mask] =...
                update_NBSPredictFigure(app.plot_data.(app.ml_model).scaledWAdjMat,...
                app.plot_data.brainRegions.labels, app.plot_results.wThresh);

            % Create weight table to access edge weights easily.
            weightTable = table;
            [weightTable.X, weightTable.Y, weightTable.Weight] = ...
                find(app.plot_results.adj);
            app.plot_results.weightTable = weightTable;

            %             % Plots thresholded data.
            %             switch app.cfig
            %                 case 'adj'
            %                     adjacencyPush_Callback(handles.adjacencyPush,[],handles);
            %                 case 'net'
            %                     networkPush_Callback(handles.networkPush,[],handles);
            %             end
            %             if isfield(handles,'brainNetFig') && isgraphics(handles.brainNetFig)
            %                 pause(0.1)
            %                 brainNetPush_Callback(handles.brainNetPush, [], handles);
            %             end
            %             handles.sizeNetworkText.String = sprintf('Nodes : %d\nEdges : %d',...
            %                 numel(handles.plotResults.labels),size(handles.plotResults.G.Edges,1));
            %             sortedTable = sortrows(table(handles.plotResults.labels,...
            %                 degree(handles.plotResults.G)),2,'descend');
            %             if handles.evalButton.Value
            %                 evalSubnet(handles);
            %             end
            %             handles.uitable1.Data = table2cell(sortedTable);
        end

        function [] = plot_adjacency(app)
            %% Go on developing!
            wBar = waitbar(0, 'Opening...');
            app.ShowLabelsButton.Enable = "off";
            app.cfig = "adj";
            imagesc(app.Figure, app.plot_results.adj);
            colorbar(app.Figure);
            app.Figure.CLim = [0, 1];
            app.Figure.XTick = [];
            app.Figure.YTick = [];
%             app.Figure.FontSize = 9;
%             app.Figure.FontName = "default";
            update_title(app);
%             dcm_obj = datacursormode(app.Figure);
%             pc_fontsize(app);
            axtoolbar(app.Figure, "export")
            disableDefaultInteractivity(app.Figure);        
            waitbar(1, wBar, 'Done!');
            close(wBar);
        end
        
        function [] = plot_circular(app)
            %% Go on developing!
%             wBar = waitbar(0, 'Opening...');
            app.ShowLabelsButton.Enable = "on";
            app.cfig = "net";
%             app.Figure.Title = app.figure_title;
            n_weights = numel(unique(app.plot_data.(app.ml_model).scaledMeanEdgeWeight));
            set(0, 'CurrentFigure', app.Figure);
            if app.show_label
                labels = app.plot_data.brainRegions.labels; 
                cG = circularGraph(app.plot_results.adj, 'Label', ...
                    labels, 'nUniqueWeight', n_weights);
            else
                cG = circularGraph(app.plot_results.adj);
            end
%             dcm_obj = datacursormode(app.Figure);
            
%             wBar = waitbar(0, 'Opening...');
%             handles.labelButton.Visible = 'on';
%             cla reset;
%             set(gca,'DefaultTextInterpreter','none');
%             title(handles.figureAxes,handles.figureTitle);
%             axes(handles.figureAxes);
%             nWeight = numel(unique(handles.plotData.(handles.cModel).scaledMeanEdgeWeight));
%             if handles.ifShowLabel
%                 labels = handles.plotData.brainRegions.labels;
%                 cG = circularGraph(handles.plotResults.adj,'Label',labels,'nUniqueWeight',nWeight);
%             else
%                 cG = circularGraph(handles.plotResults.adj);
%             end
%             handles.cFig = 'net';
%             dcm_obj = datacursormode(gcf);
%             set(dcm_obj,'Enable','on','UpdateFcn',{@dataCursorUpdateFun,handles});
%             if ~verLessThan('matlab', '9.5')
%                 % Turn off the text interpreter if MATLAB version is or newer than
%                 % R2018b.
%                 set(dcm_obj, 'Interpreter','none'); 
%             end
%             [handles] = pcFontSize(handles);
%             setappdata(handles.uipanel1,'cG',cG);
%             guidata(hObject,handles)
%             waitbar(1, wBar, 'Done!');
%             close(wBar);
        end
        
        function [] = plot_brainnet(app)
            app.ShowLabelsButton.Enable = "on";
            config_file = 'brainNetViewerConfig.mat';
            tmp = which(config_file);
            tmp = tmp(1:end-numel(config_file));
            brainRegions = app.plot_data.brainRegions;
            brainRegions(:,4) = []; brainRegions(:,4) = table(1);
            brainRegions(:,5) = table(0);
            brainRegions(~app.plot_results.mask,5) = ...
                table(app.plot_results.G.degree);
            if app.show_label
                brainRegions(:,6) = app.plot_data.brainRegions.labels;
            end
            dlmcell([tmp,'tmp.node'],table2cell(brainRegions),'\t');
            dlmcell([tmp,'tmp.edge'],num2cell(app.plot_results.adj),'\t');
            BrainNet_MapCfg('BrainMesh_ICBM152_smoothed.nv',...
                [tmp,'tmp.node'],[tmp,'tmp.edge'],'brainNetViewerConfig.mat');
            
            pc_fontsize(app);            
        end
        
        function [] = plot_confmat(app)
            %% Go on developing!
            app.cfig = "confMat";
            datacursormode off;
            truePredLabels = app.plot_results.truePredLabelsVec;
            confMat = app.plot_results.confMat; 
            labels = unique(truePredLabels);
            numlabels = size(confMat, 1); 
            confpercent = 100*confMat./repmat(sum(confMat, 1),numlabels,1);
            imagesc(app.Figure, confpercent);
            app.Figure.YLabel = "Predicted";
            app.Figure.XLabel = "Actual";
            colormap(app.Figure, flipud(gray));
            % Create strings from the matrix values and remove spaces
            textStrings = num2str([confpercent(:), confMat(:)], '%.1f%%\n%d\n');
            textStrings = strtrim(cellstr(textStrings));
            % Create x and y coordinates for the strings and plot them
            [x, y] = meshgrid(1:numlabels);
            hStrings = text(x(:), y(:),textStrings(:), ...
                'HorizontalAlignment', 'center');
            % Get the middle value of the color range


            midValue = mean(get(gca,'CLim'));
            % Choose white or black for the text color of the strings so
            % they can be easily seen over the background color
            textColors = repmat(confpercent(:) > midValue,1,3);
            set(hStrings,{'Color'},num2cell(textColors,2));
            % Setting the axis labels
            app.Figure.XTick = 1:numlabels;
            app.Figure.XTickLabel = labels;
            app.Figure.YTick = 1:numlabels;
            app.Figure.YTickLabel = labels;
            app.Figure.TickLength = [0, 0];
            app.Figure.Title = app.figure_title; 
            pc_fontsize(app);
        end

        function [] = pc_fontsize(app)
            if ispc || isunix
                app.Figure.FontSize = 8;
                app.Figure.FontName = "default";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NBSPredict)
            welcome_msg = ['\nWelcome to NBS-Predict GUI!\n',...
                'NBS-Predict GUI is graphical user interface to deliver user input and run NBS-Predict.\n',...
                'Please check MANUAL by clicking Help button!\n\n'];
            fprintf(welcome_msg);

            if nargin < 2
                % Ask to load NBSPredict file if no provided.
                [NBSPredictFile, path] = uigetfile('*.mat',...
                    'Please select NBSPredict structure saved by NBS-Predict toolbox at the end of the analysis.');
                load([path NBSPredictFile]);
            else
                if isstring(NBSPredict) || ischar(NBSPredict)
                    assert(exist(NBSPredict, 'file') == 2, 'The input file is not found!')
                    load(NBSPredict)
                end
            end

            % Load plot data.
            app.plot_data = NBSPredict.results;
            app.plot_data.MLmodels = NBSPredict.parameter.MLmodels;
            app.plot_data.brainRegions = NBSPredict.data.brainRegions;
            app.plot_data.X = NBSPredict.data.X;
            app.plot_data.y = NBSPredict.data.y;
            app.plot_data.nodes = NBSPredict.data.nodes;
            app.plot_data.edgeIdx = NBSPredict.data.edgeIdx;
            app.plot_data.ifPlotScaled = 1;
            app.plot_data.confounds = NBSPredict.data.confounds;
            app.plot_data.scalingMethod = NBSPredict.parameter.scalingMethod;
            app.show_label = 0;


            if isfield(NBSPredict.results,'bestEstimator')
                app.ml_model = app.plot_data.bestEstimator;
            else
                app.ml_model = app.plot_data.MLmodels{1};
            end

            app.if_class = check_classification(NBSPredict.data.y);
            app.plot_data.metric = NBSPredict.parameter.metric;
            app.plot_results.metric = NBSPredict.parameter.metric;
            app.plot_results.truePredLabels = ...
                app.plot_data.(app.ml_model).truePredLabels;

            if app.if_class
                app.ConfusionMatrixButton.Enable = "on";
                %                 handles =  updateConfMat(handles);
                metrics = {'Accuracy', 'Balanced_Accuracy', 'Sensitivity',...
                    'Specificity', 'Precision', 'Recall', 'F1', ...
                    'Matthews_CC', 'Cohens_Kappa', 'AUC'};
            else
                metrics = {'MSE', 'RMSE', 'Correlation', ...
                    'R_squared', 'Explained_Variance', 'MAD'};
            end
            app.MetricDropDown.Items = metrics;
            [~, idx] = ismember(app.plot_data.metric, lower(metrics));
            if idx
                app.MetricDropDown.Value = app.MetricDropDown.Items{idx};
            end

            % Set MLmodelPop handle.
            app.ModelDropDown.Items = app.plot_data.MLmodels;
            app.ModelDropDown.Value = app.ml_model;

            % Update handles structure
            app.plot_results.wThresh = 0;
            app.cfig = "adj";

            plot_updateData(app);
            plot_adjacency(app)
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Adjacency Matrix"
                    plot_adjacency(app);
                case "Circular Network"
                    plot_circular(app);
                case "BrainNet Viewer"
                    plot_brainnet(app);
                case "Confusion Matrix"
                    plot_confusion(app);
            end

        end

        % Value changed function: EvaluateSubnetworkButton
        function EvaluateSubnetworkButtonValueChanged(app, event)
            value = app.EvaluateSubnetworkButton.Value;

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.plot_results.wThresh = value;
            plot_updateData(app);
        end

        % Value changed function: ShowLabelsButton
        function ShowLabelsButtonValueChanged(app, event)
            value = app.ShowLabelsButton.Value;

        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;

        end

        % Value changed function: MetricDropDown
        function MetricDropDownValueChanged(app, event)
            value = app.MetricDropDown.Value;

        end

        % Button down function: Figure
        function FigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NBSPredictViewerUIFigure = uifigure('Visible', 'off');
            app.NBSPredictViewerUIFigure.Name = 'NBS-Predict Viewer';
            app.NBSPredictViewerUIFigure.Icon = 'icon.png';
            app.Figure = uiaxes(app.NBSPredictViewerUIFigure);
            title(app.Figure, 'Title')
            zlabel(app.Figure, 'Z')
            app.Figure.XTick = [];
            app.Figure.XTickLabel = '';
            app.Figure.YTick = [];
            app.Figure.YTickLabel = '';
            app.Figure.ZTick = [];
            app.Figure.ZTickLabel = '';
            app.Figure.ButtonDownFcn = createCallbackFcn(app, @FigureButtonDown, true);
            app.PlotTypeButtonGroup = uibuttongroup(app.NBSPredictViewerUIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.PlotTypeButtonGroup.Tag = 'plots';
            app.AdjacencyMatrixButton = uitogglebutton(app.PlotTypeButtonGroup);
            app.AdjacencyMatrixButton.Text = 'Adjacency Matrix';
            app.CircularNetworkButton = uitogglebutton(app.PlotTypeButtonGroup);
            app.CircularNetworkButton.Text = 'Circular Network';
            app.CircularNetworkButton.Value = true;
            app.BrainNetViewerButton = uitogglebutton(app.PlotTypeButtonGroup);
            app.BrainNetViewerButton.Text = 'BrainNet Viewer';
            app.ConfusionMatrixButton = uitogglebutton(app.PlotTypeButtonGroup);
            app.ConfusionMatrixButton.Enable = 'off';
            app.ConfusionMatrixButton.Text = 'Confusion Matrix';
            app.MetricLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.MetricLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.NBSPredictViewerUIFigure);
            app.MetricDropDown.Items = {'Accuracy'};
            app.MetricDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.MetricDropDown.Value = 'Accuracy';
            app.ModelLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.ModelLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.NBSPredictViewerUIFigure);
            app.ModelDropDown.Items = {'decisionTreeC'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'decisionTreeC';
            app.ShowLabelsButton = uibutton(app.NBSPredictViewerUIFigure, 'state');
            app.ShowLabelsButton.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsButtonValueChanged, true);
            app.ShowLabelsButton.Enable = 'off';
            app.ShowLabelsButton.Text = 'Show Labels';
            app.ThresholdLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.NBSPredictViewerUIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.UITable = uitable(app.NBSPredictViewerUIFigure);
            app.UITable.ColumnName = {'Node'; 'Degree'};
            app.UITable.RowName = {};
            app.NodesLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.NodesLabel.Text = 'Nodes: ';
            app.EdgesLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.EdgesLabel.Text = 'Edges:';
            app.ScoreLabel = uilabel(app.NBSPredictViewerUIFigure);
            app.ScoreLabel.Enable = 'off';
            app.ScoreLabel.Text = 'Score:';
            app.EvaluateSubnetworkButton = uibutton(app.NBSPredictViewerUIFigure, 'state');
            app.EvaluateSubnetworkButton.ValueChangedFcn = createCallbackFcn(app, @EvaluateSubnetworkButtonValueChanged, true);
            app.EvaluateSubnetworkButton.Text = 'Evaluate Subnetwork';
            % Show the figure after all components are created
            app.NBSPredictViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_NBSPredict_dev(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NBSPredictViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NBSPredictViewerUIFigure)
        end
    end
end